classdef RideModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FreezeplotCheckBox             matlab.ui.control.CheckBox
        DampingCoefEditField           matlab.ui.control.NumericEditField
        DampingCoefEditFieldLabel      matlab.ui.control.Label
        FrequencySlider                matlab.ui.control.Slider
        FrequencySliderLabel           matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        AmplitudeSliderLabel           matlab.ui.control.Label
        SimTimeSlider                  matlab.ui.control.Slider
        SimTimeSliderLabel             matlab.ui.control.Label
        PhaseInputsLabel               matlab.ui.control.Label
        RearlefttireEditField          matlab.ui.control.NumericEditField
        RearlefttireEditFieldLabel     matlab.ui.control.Label
        RearrighttireEditField_2       matlab.ui.control.NumericEditField
        RearrighttireEditField_2Label  matlab.ui.control.Label
        FrontlefttireEditField         matlab.ui.control.NumericEditField
        FrontlefttireEditFieldLabel    matlab.ui.control.Label
        FrontrighttireEditField        matlab.ui.control.NumericEditField
        FrontrighttireEditFieldLabel   matlab.ui.control.Label
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        ParkinglotButton               matlab.ui.control.RadioButton
        squarewaveButton               matlab.ui.control.RadioButton
        sinewaveButton_2               matlab.ui.control.RadioButton
        sweptsinewaveButton            matlab.ui.control.RadioButton
        RearlefttireCheckBox           matlab.ui.control.CheckBox
        RearrighttireCheckBox          matlab.ui.control.CheckBox
        FrontlefttireCheckBox          matlab.ui.control.CheckBox
        FrontrighttireCheckBox         matlab.ui.control.CheckBox
        UnspungMassRollingAngleCheckBox  matlab.ui.control.CheckBox
        UnspungMassPitchingAngleCheckBox  matlab.ui.control.CheckBox
        UnspungmasscornersCheckBox     matlab.ui.control.CheckBox
        UnspungMassCheckBox            matlab.ui.control.CheckBox
        MaxfrequnecyEditField          matlab.ui.control.NumericEditField
        MaxfrequnecyEditFieldLabel     matlab.ui.control.Label
        loadButton                     matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        chaosresponsButton             matlab.ui.control.RadioButton
        pitchresponsButton             matlab.ui.control.RadioButton
        RollresponsButton              matlab.ui.control.RadioButton
        UniformresponsButton           matlab.ui.control.RadioButton
        RoadInputsLabel                matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %  roadphase = zeros(4,1);
            % 
            % 
            % if app.UniformresponsButton.Value==true
            %     roadphase(1,1) = 1;
            % 
            % elseif app.RollresponsButton.Value==true
            %     roadphase(2,1) = 1;
            % elseif app.pitchresponsButton.Value==true
            %     roadphase(3,1) = 1;
            % elseif app.chaosresponsButton.Value==true
            %     roadphase(4,1) = 1;
            % end
            % 
            % [states,fequencyX,Y_Onesided,road_input_stored,time] = fortniteV2(roadphase);
            % 
            %  plot(app.UIAxes,time,road_input_stored(:,1))


        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            

             roadphase = zeros(4,1);


            if app.UniformresponsButton.Value==true
                roadphase(1,1) = 1;
               
            elseif app.RollresponsButton.Value==true
                roadphase(2,1) = 1;
            elseif app.pitchresponsButton.Value==true
                roadphase(3,1) = 1;
            elseif app.chaosresponsButton.Value==true
                roadphase(4,1) = 1;
            end

            % [states,fequencyX,Y_Onesided,road_input_stored,time] = fortniteV2(roadphase);
            % 
            
             
%%
%% Inputs

%phases
if roadphase(1,1) == 1 
    UniformInput = 10;
    pfr = 0;
    pfl = 0;
    prr = 0
    prl = 0;
elseif roadphase(2,1) == 1
    RollInput = 10; 
    pfr = pi/2;
    pfl = 0;
    prr = pi/2;
    prl = 0;
elseif roadphase(3,1) == 1
    pitchInput = 10;
    pfr = pi/2;
    pfl = pi/2;
    prr = 0;
    prl = 0;
elseif roadphase(4,1) == 1
    chaosInput = 10;
    pfr = 0;
    pfl = pi/2;
    prr = pi;
    prl = 3*pi/2;
end


% manual inputs for phases


if app.FrontlefttireEditField.Value >=0.001
    pfl = app.FrontlefttireEditField.Value;
else
end
if app.FrontrighttireEditField.Value >=0.001
    pfr = app.FrontrighttireEditField.Value;
else
end
if app.RearlefttireEditField.Value >=0.001
    prl = app.RearlefttireEditField.Value;
else
end
if app.RearrighttireEditField_2.Value >=0.001
    prr = app.RearrighttireEditField_2.Value;
else
end

    pfr = round(pfr*100)/100;
    pfl = round(pfl*100)/100;
    prr = round(prr*100)/100;
    prl = round(prl*100)/100;


Nfr1 = app.sweptsinewaveButton.Value;
Nfr2 = app.sinewaveButton_2.Value;

if app.sinewaveButton_2.Value ==1
    Nfr2 =1;
    Nfr3 =0;
elseif app.squarewaveButton.Value ==1
   Nfr2 = 0;
   Nfr3 = 0;
elseif app.sweptsinewaveButton.Value ==1
    Nfr1 = 1;
    Nfr3 = 1;
else
    Nfr1 = 0;
    Nfr3 = 0;
end


Nfr1 = Nfr1 + [0 0];
Nfr2 = Nfr2 + [0 0];
Nfr3 = Nfr3 + [0 0]; 

Nfl1 = Nfr1;
Nfl2 = Nfr2;
Nfl3 = Nfr3;

Nrr1 = Nfr1;
Nrr2 = Nfr2;
Nrr3 = Nfr3;

Nrl1 = Nfr1;
Nrl2 = Nfr2;
Nrl3 = Nfr3;


%% Params
ms = 300;

mfl = 4;
mrl = 4;
mfr = 4;
mrr = 4;

kfr =  30000;
krr =  30000;
kfl =  30000;
krl =  30000;

kft = 136000;
krt = 136000;

if app.DampingCoefEditField.Value >=0.1
    c = app.DampingCoefEditField.Value;


cfr = c;
crr = c;
cfl = c;
crl = c;


else
    cfr = 2000;
crr = 2000;
cfl = 2000;
crl = 2000;

end

lr  =   0.717267;
lf  =   0.808833;
laa =   0.635;
lbb =   0.635;

I_roll  =   49.960975;
I_pitch =   283.33654;
I_yaw   =   272.56808;

T = 0.5;
r = 0.2;
f = 1;

rideHeight = 0;

%% Time span and sampling rate
sr= 0.0001;
tmax=round(app.SimTimeSlider.Value*10)/10;
t_span = 0:sr:tmax;

%% Road input (janky)
noiseLevel = 0;
noise =rand(100000,1)*noiseLevel;

amp = round(app.AmplitudeSlider.Value*100)/100;
freqq = round(app.FrequencySlider.Value*100)/100;


roadfl = @(t) amp*sin(2*pi*(((r-f)/(2*T)*t.^2 + pfl))) + noise(ceil(t*100+2000+1));
road_inputfl = timeseries(roadfl(t_span'),t_span);

roadfr = @(t) amp*sin(2*pi*(((r-f)/(2*T)*t.^2 + pfr))) + noise(ceil(t*100+4000+1));
road_inputfr = timeseries(roadfr(t_span'),t_span);

roadrr = @(t) amp*sin(2*pi*(((r-f)/(2*T)*t.^2 + prr))) + noise(ceil(t*100+2000+1));
road_inputrr = timeseries(roadrr(t_span'),t_span);

roadrl = @(t) amp*sin(2*pi*(((r-f)/(2*T)*t.^2 + prl))) + noise(ceil(t*100+2000+1));
road_inputrl = timeseries(roadrl(t_span'),t_span);


u_fl = road_inputfl;     %front left tire input
u_fr = road_inputfr;     %front right tire input
u_rl = road_inputrl;     %rear left tire input
u_rr = road_inputrr;     %rear right tire input
road_input_stored = zeros(length(u_fl.data),4);
road_input_stored(:,1) = u_fr.data;
road_input_stored(:,2) = u_fl.data;
road_input_stored(:,3) = u_rr.data;
road_input_stored(:,4) = u_rl.data;
time = u_fr.Time;


%% State space 
IC = [0 0 0 0 0 0 0 0 0 0 0 0 0 0];

A =[
 0 1 0 0 0 0 0 0 0 0 0 0 0 0;
 (-kfl-kfr-krl-krr)/ms (-cfl-cfr-crl-crr)/ms (kfl)/ms (cfl)/ms (kfr)/ms (cfr)/ms (krl)/ms (crl)/ms (krr)/ms (crr)/ms (-kfl*lf-kfr*lf+krl*lr+krr*lr)/ms (-cfl*lf-cfr*lf+crl*lr+crr*lr)/ms (-kfl*laa+kfr*laa+krl*laa-krr*laa)/ms (-cfl*laa+cfr*laa+crl*laa-crr*laa)/ms;
 0 0 0 1 0 0 0 0 0 0 0 0 0 0;
 (kfl)/mfl (cfl)/mfl (-kfl-kft)/mfl (-cfl)/mfl 0 0 0 0 0 0 (kfl*lf)/mfl (cfl*lf)/mfl (kfl*laa)/mfl (cfl*laa)/mfl;
 0 0 0 0 0 1 0 0 0 0 0 0 0 0;
 (kfr)/mfr (cfr)/mfr 0 0 (-kfr-kft)/mfr (-cfr)/mfr 0 0 0 0 (kfr*lf)/mfr (cfr*lf)/mfr (kfr*laa)/mfr (cfr*laa)/mfr;
 0 0 0 0 0 0 0 1 0 0 0 0 0 0;
 (krl)/mrl (crl)/mrl 0 0 0 0 (-krl-krt)/mrl (-crl)/mrl 0 0 (krl*lr)/mrl (crl*lr)/mrl (krl*laa)/mrl (crl*laa)/mrl;
 0 0 0 0 0 0 0 0 0 1 0 0 0 0;
 (krr)/mrr (crr)/mrr 0 0 0 0 0 0 (-krr-krt)/mrr (-crr)/mrr (krr*lr)/mrr (crr*lr)/mrr (krr*laa)/mrr (crr*laa)/mrr;
 0 0 0 0 0 0 0 0 0 0 0 1 0 0;
 (-kfl*lf-kfr*lr+krl*lf+krr*lr)/I_pitch (-cfl*lf-cfr*lr+crl*lf+crr*lr)/I_pitch -(kfl*lf)/I_pitch -(cfl*lf)/I_pitch -(kfr*lr)/I_pitch -(cfr*lr)/I_pitch (krl*lf)/I_pitch (crl*lf)/I_pitch (krr*lr)/I_pitch (crr*lr)/I_pitch (-kfl*lf^2-kfr*lr^2-krl*lf^2-krr*lr^2)/I_pitch (-cfl*lf^2-cfr*lr^2-crl*lf^2-crr*lr^2)/I_pitch 0 0;
 0 0 0 0 0 0 0 0 0 0 0 0 0 1;
 (-kfl*laa-kfr*lbb+krl*laa+krr*lbb)/I_roll (-cfl*laa-cfr*lbb+crl*laa+crr*lbb)/I_roll -(kfl*laa)/I_roll -(cfl*laa)/I_roll (kfr*lbb)/I_roll (cfr*lbb)/I_roll -(krl*laa)/I_roll -(crl*laa)/I_roll (krr*lbb)/I_roll (crr*lbb)/I_roll 0 0 (-kfl*laa^2-kfr*lbb^2-krl*laa^2-krr*lbb^2)/I_roll (-cfl*laa^2-cfr*lbb^2-crl*laa^2-crr*lbb^2)/I_roll
  ];

B = [
   0 0 0 0 ;
   0 0 0 0 ;
   0 0 0 0 ;
   (kft/mfl) 0 0 0 ;  %front left tire
   0 0 0 0;
   0 (kft/mfr) 0 0 ;  %front right tire
   0 0 0 0 ;
   0  0 (krt/mfr) 0 ; %rear left tire
   0 0 0 0;
   0 0 0 (krt/mrr);   %rear right tire
   0 0 0 0;
   0 0 0 0;
   0 0 0 0;
   0 0 0 0];

C = [1 0 0 0 0 0 0 0 0 0 0 0 0 0 ; 
      0 1 0 0 0 0 0 0 0 0 0 0 0 0 ;
      0 0 1 0 0 0 0 0 0 0 0 0 0 0 ;
      0 0 0 1 0 0 0 0 0 0 0 0 0 0 ;
      0 0 0 0 1 0 0 0 0 0 0 0 0 0 ;
      0 0 0 0 0 1 0 0 0 0 0 0 0 0 ;
      0 0 0 0 0 0 1 0 0 0 0 0 0 0 ;
      0 0 0 0 0 0 0 1 0 0 0 0 0 0 ;
      0 0 0 0 0 0 0 0 1 0 0 0 0 0 ;
      0 0 0 0 0 0 0 0 0 1 0 0 0 0 ;
      0 0 0 0 0 0 0 0 0 0 1 0 0 0 ;
      0 0 0 0 0 0 0 0 0 0 0 1 0 0 ;
      0 0 0 0 0 0 0 0 0 0 0 0 1 0 ;
      0 0 0 0 0 0 0 0 0 0 0 0 0 1 ];


D = zeros(14,4);

assignin('base','A',A);
assignin('base','B',B);
assignin('base','C',C);
assignin('base','D',D);

assignin('base','u_fr',u_fr);
assignin('base','u_fl',u_fl);
assignin('base','u_rr',u_rr);
assignin('base','u_rl',u_rl);


assignin('base','Nfr1',Nfr1);
assignin('base','Nfr2',Nfr2);
assignin('base','Nfr3',Nfr3);

assignin('base','Nfl1',Nfl1);
assignin('base','Nfl2',Nfl2);
assignin('base','Nfl3',Nfl3);

assignin('base','Nrr1',Nrr1);
assignin('base','Nrr2',Nrr2);
assignin('base','Nrr3',Nrr3);

assignin('base','Nrl1',Nrl1);
assignin('base','Nrl2',Nrl2);
assignin('base','Nrl3',Nrl3);


assignin('base','pfr',pfr);
assignin('base','pfl',pfl);
assignin('base','prl',prl);
assignin('base','prr',prr);

assignin('base','amp',amp);
assignin('base','freqq',freqq);

%% running simulink, extracting from simulink


output = sim('fortniteV2Sim.slx',[0 tmax]);

states = output.states.data;
input = output.input.data;

assignin('base','states',states);
assignin('base','input',input);

%% calulations


%fft calcs

Y =fft(states(:,1));                      % two sided fft
samples = tmax/sr;                    % num of samples
Sfq = 1/sr;                           % Sampling frequency 
k = 0:samples/2;                      % contant for one sided fft
fequencyX =k*Sfq/samples;             % Computing frequency x-axis, frequncy axis to plot fft on.
Y_Onesided = abs(Y(1:samples/2+1));   % One sided fft, y-axis
Y_Onesided(1)=0;
n = find(fequencyX < app.MaxfrequnecyEditField.Value);
n = length(n);
fequencyX = fequencyX(1,1:n);
Y_Onesided = Y_Onesided(1:n,1);

%PSD calcs

psdx = (1/(Sfq*samples)) * Y_Onesided.^2; %Power of ones sided fft.
psdx(2:end-1) = 2*psdx(2:end-1);          %scaling
pow2db(psdx);                             %converting psdx to dB


%%


             if app.FreezeplotCheckBox.Value == 1
                   
             else 
                 hold(app.UIAxes_2,"off")
             end


 plot(app.UIAxes_2,time,input(:,1),LineWidth=1)
             hold(app.UIAxes_2,"on")
             plot(app.UIAxes_2,time,input(:,2),LineWidth=1)
             plot(app.UIAxes_2,time,input(:,3),LineWidth=1)
             plot(app.UIAxes_2,time,input(:,4),LineWidth=1)


             states(:,1) = states(:,1);
lr  =   0.717267;
lf  =   0.808833;
laa =   0.635;
lbb =   0.635;

fr = states(:,1)+lf*states(:,11)-laa*states(:,13);
fl = states(:,1)+lf*states(:,11)+lbb*states(:,13);
rr = states(:,1)-lr*states(:,11)+laa*states(:,13);
rl = states(:,1)-lr*states(:,11)-laa*states(:,13);


  plot(app.UIAxes,0,0,LineWidth=1)
    hold(app.UIAxes,"on")
respones = [app.UnspungMassCheckBox.Value;
            app.UnspungMassPitchingAngleCheckBox.Value;
            app.UnspungMassRollingAngleCheckBox.Value;
            app.UnspungmasscornersCheckBox.Value;
            app.FrontrighttireCheckBox.Value;
            app.FrontlefttireCheckBox.Value;
            app.RearlefttireCheckBox.Value;
            app.RearrighttireCheckBox.Value
            ];
    for i = 1:8
if app.UnspungMassCheckBox.Value ==1
     plot(app.UIAxes,time,states(:,1),LineWidth=1)
     app.UnspungMassCheckBox.Value = 0;
elseif app.UnspungMassPitchingAngleCheckBox.Value ==1
    plot(app.UIAxes,time,states(:,11),LineWidth=1)
    app.UnspungMassPitchingAngleCheckBox.Value =0;
    elseif app.UnspungMassRollingAngleCheckBox.Value ==1
    plot(app.UIAxes,time,states(:,13),LineWidth=1)
    app.UnspungMassRollingAngleCheckBox.Value =0;
    elseif app.UnspungmasscornersCheckBox.Value ==1
             plot(app.UIAxes,time,fr,LineWidth=1)
             plot(app.UIAxes,time,fl,LineWidth=1)
             plot(app.UIAxes,time,rr,LineWidth=1)
             plot(app.UIAxes,time,rl,LineWidth=1)
             app.UnspungmasscornersCheckBox.Value =0;
    elseif app.FrontrighttireCheckBox.Value ==1
    plot(app.UIAxes,time,states(:,5),LineWidth=1)
    app.FrontrighttireCheckBox.Value =0;
elseif app.FrontlefttireCheckBox.Value ==1
    plot(app.UIAxes,time,states(:,3),LineWidth=1)
    app.FrontlefttireCheckBox.Value =0;
elseif app.RearlefttireCheckBox.Value ==1
    plot(app.UIAxes,time,states(:,7),LineWidth=1)
    app.RearlefttireCheckBox.Value =0;
elseif app.RearrighttireCheckBox.Value ==1
    plot(app.UIAxes,time,states(:,9),LineWidth=1)
    app.RearrighttireCheckBox.Value =0;
end    
    end
             hold(app.UIAxes,"off")

  app.UnspungMassCheckBox.Value = respones(1);
  app.UnspungMassPitchingAngleCheckBox.Value = respones(2);
  app.UnspungMassRollingAngleCheckBox.Value = respones(3);
  app.UnspungmasscornersCheckBox.Value = respones(4);
  app.FrontrighttireCheckBox.Value =respones(5);
  app.FrontlefttireCheckBox.Value = respones(6);
  app.RearlefttireCheckBox.Value = respones(7);
  app.RearrighttireCheckBox.Value = respones(8);


             hold(app.UIAxes,"on")
             plot(app.UIAxes2,fequencyX,Y_Onesided,LineWidth=1)
             hold(app.UIAxes,"off")


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vehicle respons')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'respons')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT respons')
            xlabel(app.UIAxes2, 'frequency (Hz)')
            ylabel(app.UIAxes2, 'Amp')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Road Input')
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'respons')
            zlabel(app.UIAxes_2, 'Z')
            app.RoadInputsLabel = uilabel(app.UIFigure);
            app.RoadInputsLabel.Text = 'Road Inputs';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.UniformresponsButton = uiradiobutton(app.ButtonGroup);
            app.UniformresponsButton.Text = 'Uniform respons';
            app.UniformresponsButton.Value = true;
            app.RollresponsButton = uiradiobutton(app.ButtonGroup);
            app.RollresponsButton.Text = 'Roll respons';
            app.pitchresponsButton = uiradiobutton(app.ButtonGroup);
            app.pitchresponsButton.Text = 'pitch respons';
            app.chaosresponsButton = uiradiobutton(app.ButtonGroup);
            app.chaosresponsButton.Text = 'chaos respons';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.MaxfrequnecyEditFieldLabel = uilabel(app.UIFigure);
            app.MaxfrequnecyEditFieldLabel.Text = 'Max frequnecy';
            app.MaxfrequnecyEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnspungMassCheckBox = uicheckbox(app.UIFigure);
            app.UnspungMassCheckBox.Text = 'Unspung Mass';
            app.UnspungmasscornersCheckBox = uicheckbox(app.UIFigure);
            app.UnspungmasscornersCheckBox.Text = 'Unspung mass corners';
            app.UnspungMassPitchingAngleCheckBox = uicheckbox(app.UIFigure);
            app.UnspungMassPitchingAngleCheckBox.Text = 'Unspung Mass Pitching Angle';
            app.UnspungMassRollingAngleCheckBox = uicheckbox(app.UIFigure);
            app.UnspungMassRollingAngleCheckBox.Text = 'Unspung Mass Rolling Angle';
            app.FrontrighttireCheckBox = uicheckbox(app.UIFigure);
            app.FrontrighttireCheckBox.Text = 'Front right tire';
            app.FrontlefttireCheckBox = uicheckbox(app.UIFigure);
            app.FrontlefttireCheckBox.Text = 'Front left tire';
            app.RearrighttireCheckBox = uicheckbox(app.UIFigure);
            app.RearrighttireCheckBox.Text = 'Rear right tire';
            app.RearlefttireCheckBox = uicheckbox(app.UIFigure);
            app.RearlefttireCheckBox.Text = 'Rear left tire';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = 'Button Group';
            app.sweptsinewaveButton = uiradiobutton(app.ButtonGroup_2);
            app.sweptsinewaveButton.Text = 'swept sine wave';
            app.sweptsinewaveButton.Value = true;
            app.sinewaveButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.sinewaveButton_2.Text = 'sine wave';
            app.squarewaveButton = uiradiobutton(app.ButtonGroup_2);
            app.squarewaveButton.Text = 'square wave';
            app.ParkinglotButton = uiradiobutton(app.ButtonGroup_2);
            app.ParkinglotButton.Text = 'Parking lot';
            app.FrontrighttireEditFieldLabel = uilabel(app.UIFigure);
            app.FrontrighttireEditFieldLabel.Text = 'Front right tire';
            app.FrontrighttireEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrontlefttireEditFieldLabel = uilabel(app.UIFigure);
            app.FrontlefttireEditFieldLabel.Text = 'Front left tire';
            app.FrontlefttireEditField = uieditfield(app.UIFigure, 'numeric');
            app.RearrighttireEditField_2Label = uilabel(app.UIFigure);
            app.RearrighttireEditField_2Label.Text = 'Rear right tire';
            app.RearrighttireEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RearlefttireEditFieldLabel = uilabel(app.UIFigure);
            app.RearlefttireEditFieldLabel.Text = 'Rear left tire';
            app.RearlefttireEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseInputsLabel = uilabel(app.UIFigure);
            app.PhaseInputsLabel.Text = 'Phase Inputs';
            app.SimTimeSliderLabel = uilabel(app.UIFigure);
            app.SimTimeSliderLabel.Text = 'Sim Time';
            app.SimTimeSlider = uislider(app.UIFigure);
            app.SimTimeSlider.Limits = [0 60];
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0.001 1];
            app.AmplitudeSlider.MajorTicks = [0.001 0.2 0.4 0.6 0.8 1];
            app.AmplitudeSlider.Value = 0.001;
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [0.01 30];
            app.FrequencySlider.MajorTicks = [0.01 10 20 30 40 50];
            app.FrequencySlider.Value = 0.1;
            app.DampingCoefEditFieldLabel = uilabel(app.UIFigure);
            app.DampingCoefEditFieldLabel.Text = 'Damping Coef.';
            app.DampingCoefEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreezeplotCheckBox = uicheckbox(app.UIFigure);
            app.FreezeplotCheckBox.Text = 'Freeze plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RideModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end