classdef UGI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        ExtragraphdataButtonGroup       matlab.ui.container.ButtonGroup
        CO2CumulativeemittedButton      matlab.ui.control.RadioButton
        CO2emittedvstimeButton          matlab.ui.control.RadioButton
        CumulativeenergyconsumedButton  matlab.ui.control.RadioButton
        powervstimeButton               matlab.ui.control.RadioButton
        CumulativedistancevstimeButton  matlab.ui.control.RadioButton
        accelorationvstimeButton        matlab.ui.control.RadioButton
        FueltypeButtonGroup             matlab.ui.container.ButtonGroup
        ElectricButton                  matlab.ui.control.RadioButton
        NatralgasButton                 matlab.ui.control.RadioButton
        DieselButton                    matlab.ui.control.RadioButton
        GasolineButton                  matlab.ui.control.RadioButton
        RollingresistanceEditField      matlab.ui.control.NumericEditField
        RollingresistanceEditFieldLabel  matlab.ui.control.Label
        DragCoefficientEditField        matlab.ui.control.NumericEditField
        DragCoefficientEditFieldLabel   matlab.ui.control.Label
        frontalareaEditField            matlab.ui.control.NumericEditField
        frontalareaEditFieldLabel       matlab.ui.control.Label
        MassofcarEditField              matlab.ui.control.NumericEditField
        MassofcarEditFieldLabel         matlab.ui.control.Label
        calculateButton                 matlab.ui.control.Button
        CycleButtonGroup                matlab.ui.container.ButtonGroup
        US06Button                      matlab.ui.control.RadioButton
        NYCCButton                      matlab.ui.control.RadioButton
        HWFETButton                     matlab.ui.control.RadioButton
        UDDSButton                      matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function carparametersEditFieldValueChanged(app, event)
            value= app.carparametersEditField.Value;
            
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
          
            m=app.MassofcarEditField;
            m=m.Value;
            a=app.frontalareaEditField; 
            a=a.Value;
            Dg=app.DragCoefficientEditField;
            Dg=Dg.Value;
            R=app.RollingresistanceEditField;
            R=R.Value;
         
            parameters=[m,a,Dg,R];
            
            if app.GasolineButton.Value==true
                parameters=[m,a,Dg,R,"gasoline"];
                Eff=0.25;
                HV=45;
                D=730;
            elseif app.DieselButton.Value==true
                parameters=[m,a,Dg,R,"diesel"];
                Eff=0.25;
                HV=45;
                D=850;
            elseif app.NatralgasButton.Value==true
                parameters=[m,a,Dg,R,"naturalgas"];
                Eff=0.25;
                HV=50;
                D=0.68;
            elseif app.ElectricButton.Value==true
                parameters=[m,a,Dg,R,"electric"];
                Eff=0.8;
            end
            if app.UDDSButton.Value==true
            data=readtable("UDDS.txt");
            elseif app.US06Button.Value==true
            data=readtable("USO6.txt");
            elseif app.HWFETButton.Value==true
            data=readtable("HWFET.txt");
            elseif app.NYCCButton.Value==true
            data=readtable("NYCC.txt");
            end
            DATA=table2array(data);
            time=DATA(:,1);
           
            speeds=DATA(:,2);
            if app.US06Button.Value==true
            speeds=speeds(2:end);
            time=time(2:end);
            end
            %(you are stuck here)!!!!!!!!!!!!
            stats=drivingCycleAnalysis(parameters,time,speeds);
            speedMs=speeds./2.237;
         %%%%%%%%%%%%%----neeeeed to repot stats
                app.UITable.RowName = {'Tolal distance'; 'Max traction force'; 'Max power';'total energy';'total fuel used in kg';'total gallons of fuel used';'miles per gallons';'Mass of CO2 emitted per mile';'Total mass of CO2 emitted'};
                 app.UITable.Data=stats;

m=str2double(parameters(1));  %mass of car
A=str2double(parameters(2));  %frontal area of car
dg=str2double(parameters(3)); %drag coeficant
u=str2double(parameters(4));  %rolling friction
p=1.204;
g=9.81;   

            if app.accelorationvstimeButton.Value==true

                acceloration=zeros(length(speeds),1);
                for i=1:length(speeds)-1
                tt=time(i+1)-time(i);%s
                tt=tt/3600;%hours
                a=(speeds(i+1)-speeds(i))/tt;%
                a=(a)*(1609.344)*(1/3600)*(1/3600);%m/s^2
                acceloration(i)=a;
                end
%             hold(app.UIAxes,"on")
            plot(app.UIAxes,time,speedMs,'blue')
            yyaxis (app.UIAxes,'right')
            plot(app.UIAxes,time,acceloration,'red')
            
            elseif app.CumulativedistancevstimeButton.Value==true

            distance=zeros(length(speeds),1);
            travel=0;
            for i=1:length(speeds)-1
            tt=time(i+1)-time(i);
            tt=tt/3600;
            a=(speeds(i+1)-speeds(i))/tt;
            travel=travel+(speeds(i)*tt+0.5*a*(tt^2));
            distance(i)=travel;
          
            end
            distance(length(speeds))=travel;
            hold(app.UIAxes,"on")
            plot(app.UIAxes,time,speedMs,'blue')
             yyaxis (app.UIAxes,'right')
            plot(app.UIAxes,time,distance,'g')
           

        elseif app.powervstimeButton.Value==true

        F=zeros(length(speeds),1);
        Power=zeros(length(speeds),1);
        for i=1:length(speeds)-1
        tt=time(i+1)-time(i);%s
        tt=tt/3600;%hours
        a=(speeds(i+1)-speeds(i))/tt;%
        a=(a)*(1609.344)*(1/3600)*(1/3600);%m/s^2
        if a>=0
        else
            a=0;
        end

        v=speedMs(i); %converted to meters per sec
        F(i)=(m*a)+(0.5*p*dg*A*(v.^(2)))+(u*m*g);%N
       Power(i)=(F(i)*(v))./1000;%Kw
        end
       
%         Power=zeros(length(speeds),1);
% 
%         for i=1:length(speeds)
%          Power(i)=(F(i)*(speeds(i)./2.237))./1000;%Kw
%         end

            hold(app.UIAxes,"on")
            plot(app.UIAxes,time,speedMs,'blue')
             yyaxis (app.UIAxes,'right')
            plot(app.UIAxes,time,Power,'m')


        elseif app.CumulativeenergyconsumedButton.Value==true
            
        n=length(time);
        power=zeros(1,n);
        energy=zeros(1,n);
        E=0;
        
        for i=1:n-1
        tt=time(i+1)-time(i);%s
        tt=tt/3600;%hours
        a=(speeds(i+1)-speeds(i))/tt;%
        a=(a)*(1609.344)*(1/3600)*(1/3600);%m/s^2
        if a>=0
        else
            a=0;
        end

        v=speeds(i) /2.237; %converted to meters per sec
        F=(m*a)+(0.5*p*dg*A*(v^2))+(u*m*g);%N
            power(i)=(F*v)/10;
        end
        
        for i=1:n-1
           E=0.5*(power(i)+power(i+1))*(time(i+1)-time(i))+E;
           energy(i)=(E/1e+6)/Eff;
        end
       energy(n)=energy(n-1);
            hold(app.UIAxes,"on")
            plot(app.UIAxes,time,speedMs,'blue')
             yyaxis (app.UIAxes,'right')
            plot(app.UIAxes,time,energy,'.')
          
            elseif app.CO2emittedvstimeButton.Value==true
               
    %-------------------------------------------------------------                      
        n=length(time);
        power=zeros(1,n);
        energy=zeros(1,n);
        E=0;
        
        for i=1:n-1
        tt=time(i+1)-time(i);%s
        tt=tt/3600;%hours
        a=(speeds(i+1)-speeds(i))/tt;%
        a=(a)*(1609.344)*(1/3600)*(1/3600);%m/s^2
        if a>=0
        else
        a=0;
        end
        v=speeds(i) /2.237; %converted to meters per sec
        F=(m*a)+(0.5*p*dg*A*(v^2))+(u*m*g);%N
            power(i)=(F*v)/1000;
            
        end
        
        for i=1:n-1
           E=0.5*(power(i)+power(i+1))*(time(i+1)-time(i));
           energy(i)=(E/1e+6)/Eff;
        end
        fuell=zeros(n,1);
        gallons=zeros(n,1);
        CO2=zeros(n,1);
            

        for i=1:n-1
            fuell(i)=energy(i)/HV;
            gallons(i)=(fuell(i)/D)*264.172;
            
            CO2(i)=(gallons(i)*8887)*1000;
            
        end
            hold(app.UIAxes,"on")
            plot(app.UIAxes,time,speedMs,'blue')
             yyaxis (app.UIAxes,'right')
            plot(app.UIAxes,time,CO2,'k')

            
  %-----------------------------------------------------------
            elseif app.CO2CumulativeemittedButton.Value==true
 n=length(time);
        power=zeros(1,n);
        energy=zeros(1,n);
        E=0;
        CO22=0;
        for i=1:n-1
        tt=time(i+1)-time(i);%s
        tt=tt/3600;%hours
        a=(speeds(i+1)-speeds(i))/tt;%
        a=(a)*(1609.344)*(1/3600)*(1/3600);%m/s^2
        if a>=0
        else
        a=0;
        end
        v=speeds(i) /2.237; %converted to meters per sec
        F=(m*a)+(0.5*p*dg*A*(v^2))+(u*m*g);%N
            power(i)=(F*v)/10;
        end
        
        for i=1:n-1
           E=0.5*(power(i)+power(i+1))*(time(i+1)-time(i));
           energy(i)=(E/1e+6)/Eff;
        end
        fuell=zeros(n,1);
        gallons=zeros(n,1);
        CO2=zeros(n,1);
            
FUEL=0;
        for i=1:n-1
            FUEL=(energy(i)/HV)+FUEL;
            fuell(i)=FUEL;
            gallons(i)=(fuell(i)/D)*264.172;
            
            CO2(i)=(gallons(i)*8887);%+CO22;
        
        end
         CO2(n)=stats(9);

            hold(app.UIAxes,"on")
            plot(app.UIAxes,time,speedMs,'blue')
             yyaxis (app.UIAxes,'right')
            plot(app.UIAxes,time,CO2,'k')


            end


        end

        % Callback function
        function FueltypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FueltypeButtonGroup.SelectedObject;
            
        end

        % Selection changed function: FueltypeButtonGroup
        function FueltypeButtonGroupSelectionChanged2(app, event)
            selectedButton = app.FueltypeButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'data')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'speed')
            zlabel(app.UIAxes, 'Z')
            app.CycleButtonGroup = uibuttongroup(app.UIFigure);
            app.CycleButtonGroup.Title = 'Cycle';
            app.UDDSButton = uiradiobutton(app.CycleButtonGroup);
            app.UDDSButton.Text = 'UDDS';
            app.UDDSButton.Value = true;
            app.HWFETButton = uiradiobutton(app.CycleButtonGroup);
            app.HWFETButton.Text = 'HWFET';
            app.NYCCButton = uiradiobutton(app.CycleButtonGroup);
            app.NYCCButton.Text = 'NYCC';
            app.US06Button = uiradiobutton(app.CycleButtonGroup);
            app.US06Button.Text = 'US06';
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.MassofcarEditFieldLabel = uilabel(app.UIFigure);
            app.MassofcarEditFieldLabel.Text = 'Mass of car';
            app.MassofcarEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofcarEditField.Value = 2000;
            app.frontalareaEditFieldLabel = uilabel(app.UIFigure);
            app.frontalareaEditFieldLabel.Text = 'frontal area ';
            app.frontalareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.frontalareaEditField.Value = 3;
            app.DragCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.DragCoefficientEditFieldLabel.Text = 'Drag Coefficient';
            app.DragCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.DragCoefficientEditField.Value = 0.03;
            app.RollingresistanceEditFieldLabel = uilabel(app.UIFigure);
            app.RollingresistanceEditFieldLabel.Text = 'Rolling resistance';
            app.RollingresistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollingresistanceEditField.Value = 0.02;
            app.FueltypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FueltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FueltypeButtonGroupSelectionChanged2, true);
            app.FueltypeButtonGroup.Title = 'Fuel type';
            app.GasolineButton = uiradiobutton(app.FueltypeButtonGroup);
            app.GasolineButton.Text = 'Gasoline';
            app.GasolineButton.Value = true;
            app.DieselButton = uiradiobutton(app.FueltypeButtonGroup);
            app.DieselButton.Text = 'Diesel';
            app.NatralgasButton = uiradiobutton(app.FueltypeButtonGroup);
            app.NatralgasButton.Text = 'Natral gas';
            app.ElectricButton = uiradiobutton(app.FueltypeButtonGroup);
            app.ElectricButton.Text = 'Electric';
            app.ExtragraphdataButtonGroup = uibuttongroup(app.UIFigure);
            app.ExtragraphdataButtonGroup.Title = 'Extra graph data';
            app.accelorationvstimeButton = uiradiobutton(app.ExtragraphdataButtonGroup);
            app.accelorationvstimeButton.Text = 'acceloration vs time';
            app.accelorationvstimeButton.Value = true;
            app.CumulativedistancevstimeButton = uiradiobutton(app.ExtragraphdataButtonGroup);
            app.CumulativedistancevstimeButton.Text = 'Cumulative distance vs time';
            app.powervstimeButton = uiradiobutton(app.ExtragraphdataButtonGroup);
            app.powervstimeButton.Text = 'power vs time';
            app.CumulativeenergyconsumedButton = uiradiobutton(app.ExtragraphdataButtonGroup);
            app.CumulativeenergyconsumedButton.Text = 'Cumulative energy consumed';
            app.CO2emittedvstimeButton = uiradiobutton(app.ExtragraphdataButtonGroup);
            app.CO2emittedvstimeButton.Text = 'CO2 emitted vs time';
            app.CO2CumulativeemittedButton = uiradiobutton(app.ExtragraphdataButtonGroup);
            app.CO2CumulativeemittedButton.Text = 'CO2 Cumulative emitted';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Data'};
            app.UITable.RowName = {''; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UGI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end