classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        maximumxEditField            matlab.ui.control.EditField
        maximumxEditFieldLabel       matlab.ui.control.Label
        minimumxvalueEditField       matlab.ui.control.EditField
        minimumxvalueEditFieldLabel  matlab.ui.control.Label
        whatplotButtonGroup          matlab.ui.container.ButtonGroup
        contorButton                 matlab.ui.control.RadioButton
        surfButton                   matlab.ui.control.RadioButton
        meshButton                   matlab.ui.control.RadioButton
        enterfunctionEditField       matlab.ui.control.EditField
        enterfunctionEditFieldLabel  matlab.ui.control.Label
        enterfunctionTextArea        matlab.ui.control.TextArea
        enterfunctionTextAreaLabel   matlab.ui.control.Label
        clearButton                  matlab.ui.control.Button
        drawButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            userString= app.enterfunctionEditField.Value;
            funcString=strcat('@(x,y)',userString);
            try
            func=str2func(funcString);%converts
            catch ME
              %  fprintf('there is a error %s : %s \n', ME.identifier)
              fig=uifigure;
              uialert(fig,'please be smart','invalid')
            return
            end
            x=linspace(-3,3,100);
            y=linspace(-3,3,100);
            [X,Y]=meshgrid(x,y);
            z=func(X,Y);
            mesh(app.UIAxes,X,Y,z)
         
            if app.surfButton.Value==true
                surf(app.UIAxes,X,Y,z)
            elseif app.meshButton.Value==true
                mesh(app.UIAxes,X,Y,z)
            elseif app.contorButton.Value==true
                contour(app.UIAxes,X,Y,z)
            end
        end

        % Button pushed function: clearButton
        function clearhappy(app, event)
            cla(app.UIAxes)
        end

        % Value changed function: maximumxEditField
        function maxX(app, event)
            value = app.maximumxEditField.Value;
           
        end

        % Value changed function: minimumxvalueEditField
        function minX(app, event)
            value = app.minimumxvalueEditField.Value;
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'3D plot'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'draw';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearhappy, true);
            app.clearButton.Text = 'clear';
            app.enterfunctionTextAreaLabel = uilabel(app.UIFigure);
            app.enterfunctionTextAreaLabel.Text = 'enter function';
            app.enterfunctionTextArea = uitextarea(app.UIFigure);
            app.enterfunctionEditFieldLabel = uilabel(app.UIFigure);
            app.enterfunctionEditFieldLabel.Text = 'enter function';
            app.enterfunctionEditField = uieditfield(app.UIFigure, 'text');
            app.enterfunctionEditField.Value = 'x^2+y^2';
            app.whatplotButtonGroup = uibuttongroup(app.UIFigure);
            app.whatplotButtonGroup.Title = 'what plot?';
            app.meshButton = uiradiobutton(app.whatplotButtonGroup);
            app.meshButton.Text = 'mesh';
            app.meshButton.Value = true;
            app.surfButton = uiradiobutton(app.whatplotButtonGroup);
            app.surfButton.Text = 'surf';
            app.contorButton = uiradiobutton(app.whatplotButtonGroup);
            app.contorButton.Text = 'contor';
            app.minimumxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.minimumxvalueEditFieldLabel.Text = 'minimum x value';
            app.minimumxvalueEditField = uieditfield(app.UIFigure, 'text');
            app.minimumxvalueEditField.ValueChangedFcn = createCallbackFcn(app, @minX, true);
            app.maximumxEditFieldLabel = uilabel(app.UIFigure);
            app.maximumxEditFieldLabel.Text = 'maximum x';
            app.maximumxEditField = uieditfield(app.UIFigure, 'text');
            app.maximumxEditField.ValueChangedFcn = createCallbackFcn(app, @maxX, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end